classdef RPESelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        SourceImageEditFieldLabel  matlab.ui.control.Label
        SourceImageEditField       matlab.ui.control.EditField
        SelectButton1              matlab.ui.control.Button
        OpenButton1                matlab.ui.control.Button
        BorderImageLabel           matlab.ui.control.Label
        BorderImageEditField       matlab.ui.control.EditField
        SelectButton2              matlab.ui.control.Button
        OpenButton2                matlab.ui.control.Button
        ShowMaskSwitchLabel        matlab.ui.control.Label
        ShowMaskSwitch             matlab.ui.control.Switch
        CenterPanel                matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
        ZoneNumberEditFieldLabel   matlab.ui.control.Label
        ZoneNumberEditField        matlab.ui.control.NumericEditField
        UpdateButton               matlab.ui.control.Button
        SaveAsButtonGroup          matlab.ui.container.ButtonGroup
        BorderButton               matlab.ui.control.RadioButton
        CellRegionButton           matlab.ui.control.RadioButton
        SaveButton                 matlab.ui.control.Button
        RedButton                  matlab.ui.control.StateButton
        GreenButton                matlab.ui.control.StateButton
        ChangeMaskLabel            matlab.ui.control.Label
        SwitchButton               matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        SourceImage
        GrayImage
        BorderImage
        PixelIdxList
        LabelMap
        RegionNum
        RegionArea
        SelectFlag
        BoundaryIdx
        OutlierIdx
        ToolBar
        MouseClicked
        ClickedPosition
        SettingState
    end
    
    methods (Access = private)
        
        function ResetBorderInfo(app)
            app.BorderImage = [];
            app.PixelIdxList = [];
            app.LabelMap = [];
            app.RegionNum = [];
            app.RegionArea = [];
            app.SelectFlag = [];
            app.BoundaryIdx = [];
            app.OutlierIdx = [];
        end
        
        function idxSet = FindBoundaryIdx(app)
            [~, i] = max(app.RegionArea);
            bgIdx = app.PixelIdxList{i};
            bg = zeros(size(app.BorderImage), 'logical');
            bg(bgIdx) = 1;
            se = strel('diamond', 2);
            boundary = imdilate(bg, se);
            boundary = boundary & ~bg;
            boundaryPixel = find(boundary);
            IsMember = @(x) ~isempty(find(ismember(x, boundaryPixel), 1));
            Flags = cellfun(IsMember, app.PixelIdxList);
            idxSet = find(Flags);
            idxSet = [idxSet i];
        end
        
        function idxSet = FindOutlierIdx(app)
            zones = app.ZoneNumberEditField.Value;   
            center = size(app.BorderImage) / 2;
            cc = bwconncomp(app.BorderImage, 4);
            stats = regionprops(cc, 'Centroid', 'Solidity', 'Eccentricity', 'Area');
            centroid = cat(1, stats.Centroid);
            solidity = cat(1, stats.Solidity);
            eccentricity= cat(1, stats.Eccentricity);
            area = cat(1, stats.Area);
            dist = arrayfun(@(x) norm(center-x.Centroid), stats);
            groups = discretize(dist, zones);
            
            idxSet = [];
            for i = 1:zones
                idx = groups == i;
                so = solidity(idx);
                ec = eccentricity(idx);
                ar = area(idx);
                ce = centroid(idx, :);
                isout = isoutlier(so) | isoutlier(ec) | isoutlier(ar);
                ce_out = ce(isout, :);
                ce_out = mat2cell(ce_out, ones(1, sum(isout)));
                iset = cellfun(@(x) app.LabelMap(round(x(2)), round(x(1))), ce_out);
                idxSet = [idxSet; iset];
            end
        end
        
        function ResetUIAxes(app)
            delete(app.UIAxes);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '');
            xlabel(app.UIAxes, '');
            ylabel(app.UIAxes, '');
            app.UIAxes.Visible = 'off';
            app.UIAxes.Position = [5 5 451 481];
            app.ToolBar = axtoolbar(app.UIAxes, {'pan', 'zoomin', 'zoomout', 'restoreview'});
        end
        
        function RefreshPlot(app)
            if isempty(app.SourceImage)
                ResetUIAxes(app);
                return;
            end
            
            if isempty(app.BorderImage) || ~app.ShowMaskSwitch.Value
                imshow(app.GrayImage, 'Parent', app.UIAxes);
            else
                greeMask = app.SelectFlag;
                redMask = app.LabelMap ~= 0 & ~app.SelectFlag;
                im = app.GrayImage;
                im = labeloverlay(im, greeMask, 'Colormap', [0 1 0], 'Transparency', 0.8);
                im = labeloverlay(im, redMask, 'Colormap', [1 0 0], 'Transparency', 0.8);
                imshow(im, 'Parent', app.UIAxes);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SourceImage = [];
            app.GrayImage = [];
            app.MouseClicked = 0;
            app.ClickedPosition = [];
            app.SettingState = -1;
            ResetBorderInfo(app);
            
            set(app.ShowMaskSwitch, 'ItemsData', [0 1]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {491, 491, 491};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {491, 491};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 246};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: SelectButton1
        function SelectButton1Pushed(app, event)
            [file, path] = uigetfile('*.*');
            if ~isequal(file, 0)
                app.SourceImageEditField.Value = fullfile(path, file);
            end
            figure(app.UIFigure);
        end

        % Button pushed function: OpenButton1
        function OpenButton1Pushed(app, event)
            ResetUIAxes(app);
            
            try
                app.SourceImage = imread(app.SourceImageEditField.Value);
            catch
                app.SourceImage = [];
                app.GrayImage = [];
                RefreshPlot(app);
                uialert(app.UIFigure, 'Cannot open image!', 'Error');
                return;
            end
            
            if ndims(app.SourceImage) == 3
                app.GrayImage = repmat(app.SourceImage(:, :, 2), [1 1 3]);
            elseif ndims(app.SourceImage) == 2
                app.GrayImage = repmat(app.SourceImage, [1 1 3]);
            else
                app.SourceImage = [];
                app.GrayImage = [];
                uialert(app.UIFigure, 'Incorrect image format!', 'Error');
            end
            
            RefreshPlot(app);
        end

        % Button pushed function: SelectButton2
        function SelectButton2Pushed(app, event)
            [file, path] = uigetfile('*.*');
            if ~isequal(file, 0)
                app.BorderImageEditField.Value = fullfile(path, file);
            end
            figure(app.UIFigure);
        end

        % Button pushed function: OpenButton2
        function OpenButton2Pushed(app, event)
            try
                app.BorderImage = imread(app.BorderImageEditField.Value);
            catch
                ResetBorderInfo(app);
                uialert(app.UIFigure, 'Cannot open image!', 'Error');
                RefreshPlot(app);
                return;
            end
            
            if ndims(app.BorderImage) ~= 2
                ResetBorderInfo(app);
                uialert(app.UIFigure, 'Border should be binary!', 'Error');
                RefreshPlot(app);
                return;
            end
            
            if ~islogical(app.BorderImage)
%                 thresh = graythresh(app.BorderImage);
%                 app.BorderImage = im2bw(app.BorderImage, thresh);
                app.BorderImage = imbinarize(int8(app.BorderImage));
            end
            
            cc = bwconncomp(app.BorderImage, 4);
            app.PixelIdxList = cc.PixelIdxList;
            app.LabelMap = zeros(size(app.BorderImage), 'int32');
            app.RegionArea = cellfun(@numel, app.PixelIdxList);
            app.RegionNum = size(app.RegionArea, 2);
            app.SelectFlag = zeros(size(app.BorderImage), 'logical');
            for i = 1:app.RegionNum
                app.LabelMap(app.PixelIdxList{i}) = i;
            end
            
            app.BoundaryIdx = FindBoundaryIdx(app);
            app.OutlierIdx = FindOutlierIdx(app);                
            for i = 1:app.RegionNum
                if ~ismember(i, app.OutlierIdx) && ~ismember(i, app.BoundaryIdx)
                    app.SelectFlag(app.PixelIdxList{i}) = 1;
                else
                    app.SelectFlag(app.PixelIdxList{i}) = 0;
                end
            end
            
            RefreshPlot(app);
            uialert(app.UIFigure, 'Border Image Loaded!', 'Success', 'Icon', 'success');
        end

        % Value changed function: ShowMaskSwitch
        function ShowMaskSwitchValueChanged(app, event)
            RefreshPlot(app);
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if ~isempty(app.BorderImage) && app.ShowMaskSwitch.Value && app.SettingState > -1
                coord = get(app.UIAxes, 'CurrentPoint');
                row = int32(coord(1, 2));
                col = int32(coord(1, 1));
                OnAxes =  row < size(app.BorderImage, 1) && col < size(app.BorderImage, 2) && ...
                    col > app.UIAxes.XLim(1) && col < app.UIAxes.XLim(2) && ...
                    row > app.UIAxes.YLim(1) && row < app.UIAxes.YLim(2);
                if OnAxes && ~app.MouseClicked
                    if app.SettingState < 2
                        app.MouseClicked = 1;
                        app.ClickedPosition = [row col];
                    else
                        label = app.LabelMap(row, col);
                        flag = app.SelectFlag(row, col);
                        if flag >= 0 && label > 0
                            app.SelectFlag(app.PixelIdxList{label}) = ~flag;
                            RefreshPlot(app);
                        end
                    end
                end
            else
                app.MouseClicked = 0;
                app.ClickedPosition = [];
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if ~isempty(app.BorderImage) && app.ShowMaskSwitch.Value && ...
                    app.SettingState > -1 && app.SettingState < 2
                coord = get(app.UIAxes, 'CurrentPoint');
                row = int32(coord(1, 2));
                col = int32(coord(1, 1));
                OnAxes =  row < size(app.BorderImage, 1) && col < size(app.BorderImage, 2) && ...
                    col > app.UIAxes.XLim(1) && col < app.UIAxes.XLim(2) && ...
                    row > app.UIAxes.YLim(1) && row < app.UIAxes.YLim(2);
                if OnAxes && app.MouseClicked
                    row = [app.ClickedPosition(1), row];
                    col = [app.ClickedPosition(2), col];
                    row = sort(row);
                    col = sort(col);
                    roi = app.LabelMap(row(1):row(2), col(1):col(2));
                    labels = unique(nonzeros(roi));
                    for i = 1:size(labels, 1)
                        app.SelectFlag(app.PixelIdxList{labels(i)}) = app.SettingState;
                    end
                    RefreshPlot(app);
                end
            end
            
            app.MouseClicked = 0;
            app.ClickedPosition = [];
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            if ~isempty(app.BorderImage) 
                app.OutlierIdx = FindOutlierIdx(app);
                
                for i = 1:app.RegionNum
                    if ~ismember(i, app.OutlierIdx) && ~ismember(i, app.BoundaryIdx)
                        app.SelectFlag(app.PixelIdxList{i}) = 1;
                    else
                        app.SelectFlag(app.PixelIdxList{i}) = 0;
                    end
                end
                
                RefreshPlot(app);
                uialert(app.UIFigure, 'Plot Updated!', 'Success', 'Icon', 'success');
            end
        end

        % Value changed function: RedButton
        function RedButtonValueChanged(app, event)
            if app.RedButton.Value == 0
                app.SettingState = -1;
            else
                app.SettingState = 0;
                app.GreenButton.Value = 0;
                app.SwitchButton.Value = 0;
                btnArray = app.ToolBar.Children;
                for i = 1:size(btnArray, 1)
                    if strcmp(btnArray(i).Type, 'toolbarstatebutton')
                        if strcmp(btnArray(i).Value, 'on')
                            e = btnArray(i);
                            d = struct;
                            d.Source = e;
                            d.Axes = app.UIAxes;
                            d.EvenName = 'ValueChanged';
                            d.Value = 'off';
                            d.PreviousValue = 'on';
                            feval(btnArray(i).ValueChangedFcn,e,d);
                        end
                    end
                end
            end
        end

        % Value changed function: GreenButton
        function GreenButtonValueChanged(app, event)
            if app.GreenButton.Value == 0
                app.SettingState = -1;
            else
                app.SettingState = 1;
                app.RedButton.Value = 0;
                app.SwitchButton.Value = 0;
                btnArray = app.ToolBar.Children;
                for i = 1:size(btnArray, 1)
                    if strcmp(btnArray(i).Type, 'toolbarstatebutton')
                        if strcmp(btnArray(i).Value, 'on')
                            e = btnArray(i);
                            d = struct;
                            d.Source = e;
                            d.Axes = app.UIAxes;
                            d.EvenName = 'ValueChanged';
                            d.Value = 'off';
                            d.PreviousValue = 'on';
                            feval(btnArray(i).ValueChangedFcn,e,d);
                        end
                    end
                end
            end
        end

        % Value changed function: SwitchButton
        function SwitchButtonValueChanged(app, event)
            if app.SwitchButton.Value == 0
                app.SettingState = -1;
            else
                app.SettingState = 2;
                app.RedButton.Value = 0;
                app.GreenButton.Value = 0;
                btnArray = app.ToolBar.Children;
                for i = 1:size(btnArray, 1)
                    if strcmp(btnArray(i).Type, 'toolbarstatebutton')
                        if strcmp(btnArray(i).Value, 'on')
                            e = btnArray(i);
                            d = struct;
                            d.Source = e;
                            d.Axes = app.UIAxes;
                            d.EvenName = 'ValueChanged';
                            d.Value = 'off';
                            d.PreviousValue = 'on';
                            feval(btnArray(i).ValueChangedFcn,e,d);
                        end
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~isempty(app.BorderImage)
                [file, path] = uiputfile('*.*','File Selection','result.tif');
                fullpath = fullfile(path, file);
                if strcmp(app.SaveAsButtonGroup.SelectedObject.Text, 'Border')
                    se = strel('diamond', 1);
                    border = imdilate(app.SelectFlag, se);
                    border = border & ~app.SelectFlag;
                    border = 255 * uint8(border);
                    imwrite(border, fullpath);
                elseif strcmp(app.SaveAsButtonGroup.SelectedObject.Text, 'Cell Region')
                    region = 255 * uint8(app.SelectFlag);
                    imwrite(region, fullpath);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SourceImageEditFieldLabel = uilabel(app.LeftPanel);
            app.SourceImageEditFieldLabel.Text = 'Source Image';
            app.SourceImageEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectButton1 = uibutton(app.LeftPanel, 'push');
            app.SelectButton1.ButtonPushedFcn = createCallbackFcn(app, @SelectButton1Pushed, true);
            app.SelectButton1.Text = 'Select';
            app.OpenButton1 = uibutton(app.LeftPanel, 'push');
            app.OpenButton1.ButtonPushedFcn = createCallbackFcn(app, @OpenButton1Pushed, true);
            app.OpenButton1.Text = 'Open';
            app.BorderImageLabel = uilabel(app.LeftPanel);
            app.BorderImageLabel.Text = 'Border Image';
            app.BorderImageEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectButton2 = uibutton(app.LeftPanel, 'push');
            app.SelectButton2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton2Pushed, true);
            app.SelectButton2.Text = 'Select';
            app.OpenButton2 = uibutton(app.LeftPanel, 'push');
            app.OpenButton2.ButtonPushedFcn = createCallbackFcn(app, @OpenButton2Pushed, true);
            app.OpenButton2.Text = 'Open';
            app.ShowMaskSwitchLabel = uilabel(app.LeftPanel);
            app.ShowMaskSwitchLabel.Text = 'Show Mask';
            app.ShowMaskSwitch = uiswitch(app.LeftPanel, 'slider');
            app.ShowMaskSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowMaskSwitchValueChanged, true);
            app.ShowMaskSwitch.Value = 'On';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.ZoneNumberEditFieldLabel = uilabel(app.RightPanel);
            app.ZoneNumberEditFieldLabel.Text = 'Zone Number';
            app.ZoneNumberEditField = uieditfield(app.RightPanel, 'numeric');
            app.ZoneNumberEditField.Limits = [1 Inf];
            app.ZoneNumberEditField.RoundFractionalValues = 'on';
            app.ZoneNumberEditField.Value = 20;
            app.UpdateButton = uibutton(app.RightPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SaveAsButtonGroup = uibuttongroup(app.RightPanel);
            app.SaveAsButtonGroup.Title = 'Save As';
            app.BorderButton = uiradiobutton(app.SaveAsButtonGroup);
            app.BorderButton.Text = 'Border';
            app.BorderButton.Value = true;
            app.CellRegionButton = uiradiobutton(app.SaveAsButtonGroup);
            app.CellRegionButton.Text = 'Cell Region';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RedButton = uibutton(app.RightPanel, 'state');
            app.RedButton.ValueChangedFcn = createCallbackFcn(app, @RedButtonValueChanged, true);
            app.RedButton.Text = 'Red';
            app.GreenButton = uibutton(app.RightPanel, 'state');
            app.GreenButton.ValueChangedFcn = createCallbackFcn(app, @GreenButtonValueChanged, true);
            app.GreenButton.Text = 'Green';
            app.ChangeMaskLabel = uilabel(app.RightPanel);
            app.ChangeMaskLabel.Text = 'Change Mask';
            app.SwitchButton = uibutton(app.RightPanel, 'state');
            app.SwitchButton.ValueChangedFcn = createCallbackFcn(app, @SwitchButtonValueChanged, true);
            app.SwitchButton.Text = 'Switch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RPESelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end